classdef AngleCoefficients < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AngleCoeff           matlab.ui.Figure
        UITableCoefficients  matlab.ui.control.Table
    end


    properties (Access = public)
        Data = [33631.52 0.002506 129 212.08; 31511.65 0.002551 127 148.99];  %Значения коэффициентов по умолчанию
        FileName = 'Расчётные коэффициенты инклинометра.txt';
    end

    methods (Access = private)
    
        function SaveCoeff(app) %Сохранение коэффициентов в файл
            for i = 1:size(app.UITableCoefficients.Data,1)
                for j = 1:size(app.UITableCoefficients.Data,2)
                    TempData(i,j) = str2double(app.UITableCoefficients.Data{i,j});
                end
            end
            dlmwrite(app.FileName, TempData, 'delimiter','\t', 'precision', 10, 'newline', 'pc'); %Запись изменений в файл
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Выставление позиции
            ScreenSize = get(0, 'ScreenSize'); %Get screen size
            ScreenWidth = ScreenSize(3); ScreenHeight = ScreenSize(4); %Размеры экрана
            UiWidth = app.AngleCoeff.Position(3); UiHeight = app.AngleCoeff.Position(4); %Размеры приложения
            app.AngleCoeff.Position = [ScreenWidth/2 - UiWidth/2 ScreenHeight/2 - UiHeight/2 UiWidth UiHeight]; %Размещение по центру экрана            
            app.UITableCoefficients.RowName = {'Вдоль', 'Поперек'}; %Подпись строк
            %Выставление коэффициентов
            if isfile(app.FileName)
               app.Data = dlmread(app.FileName); %Чтение исходных коэффициентов из файла
            end 
            for i = 1:size(app.Data,1)
               for j = 1:size(app.Data,2)
                  DataStr{i,j} = sprintf('%f',app.Data(i,j)); %Изменение формата записи
               end               
            end                               
            app.UITableCoefficients.Data = DataStr; %Заполнение полей таблицы
            app.UITableCoefficients.ColumnFormat = {'numeric','numeric','numeric'}; %Ввод в поля ячеек только числовых значений
            
            if ~isfile(app.FileName)
               SaveCoeff(app); %Сохранение коэффициентов по умолчанию в файл 
            end
            
        end

        % Cell edit callback: UITableCoefficients
        function UITableCoefficientsCellEdit(app, event)
            SaveCoeff(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AngleCoeff = uifigure;
            app.AngleCoeff.Name = 'Расчётные коэффициенты инклинометра';
            app.UITableCoefficients = uitable(app.AngleCoeff);
            app.UITableCoefficients.ColumnName = {'Ноль (Н)'; 'Коэффициент (К )'; 'Баланс (ББ)'; 'Калибровка (Д)'};
            app.UITableCoefficients.RowName = {};
            app.UITableCoefficients.ColumnEditable = [true true false true];
            app.UITableCoefficients.CellEditCallback = createCallbackFcn(app, @UITableCoefficientsCellEdit, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = AngleCoefficients

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AngleCoeff)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AngleCoeff)
        end
    end
end