classdef SettingsOption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Settings_option       matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Save                  matlab.ui.container.Tab
        Label                 matlab.ui.control.Label
        ListBoxRegressionArg  matlab.ui.control.ListBox
        AngleCoeff            matlab.ui.container.Tab
        UITableCoefficients   matlab.ui.control.Table
    end


    properties (Access = public)
        Data = [33631.52 0.002506 129 212.08; 31511.65 0.002551 127 148.99];  %Значения коэффициентов по умолчанию
        FileNameAngle = 'Расчётные коэффициенты инклинометра.txt';
        FileNameSettings = 'Опции расчёта.txt';
    end

    methods (Access = private)
    
        function SaveCoeff(app) %Сохранение коэффициентов в файл
            for i = 1:size(app.UITableCoefficients.Data,1)
                for j = 1:size(app.UITableCoefficients.Data,2)
                    TempData(i,j) = str2double(app.UITableCoefficients.Data{i,j});
                end
            end
            dlmwrite(app.FileNameAngle, TempData, 'delimiter','\t', 'precision', 10, 'newline', 'pc'); %Запись изменений в файл
        end
        
        function SaveSettings(app) %Сохранение опций расчёта в файл
            RawRegressionArg = app.ListBoxRegressionArg.Value; %Выбранные аргументы регрессионной таблицы
            fileID = fopen(app.FileNameSettings, 'w'); % Открытие файла для записи
            formatSpec = '%s \n'; %Формат вывода
            for i = 1:length(RawRegressionArg)
               switch RawRegressionArg{i}
                   case 'Исходные ускорения'
                       Res = 'НУ';
                   case 'Ускорения'
                       Res = 'У';
                   case 'Возрастающие ускорения'
                       Res = 'УВ'; 
                   case 'Нейтральные ускорения'
                       Res = 'УН'; 
                   case 'Убывающие ускорения'
                       Res = 'УУ'; 
                   case 'Затухающие ускорения'
                       Res = 'УЗ'; 
               end  
               fprintf(fileID, formatSpec, Res); %Запись данных
            end
            fclose(fileID); %Закрытие файла
        end
        
        function ReadSettings(app) %Чтение опций расчета из файла
            fileID = fopen(app.FileNameSettings, 'r'); % Открытие файла для чтения
            formatSpec = '%s \n'; %Формат данных
            RawRegressionArg = textscan(fileID, formatSpec); %Чтение данных из файла
            fclose(fileID); %Закрытие файла   
            RegressionArg = RawRegressionArg{1}; %Коррекция формата
            app.ListBoxRegressionArg.Value = {}; %Сброс выбора по умолчанию
            for i = 1:length(RegressionArg)
               switch RegressionArg{i}
                   case 'НУ'
                       Res = 'Исходные ускорения';
                   case 'У'
                       Res = 'Ускорения';
                   case 'УВ'
                       Res = 'Возрастающие ускорения'; 
                   case 'УН'
                       Res = 'Нейтральные ускорения'; 
                   case 'УУ'
                       Res = 'Убывающие ускорения'; 
                   case 'УЗ'
                       Res = 'Затухающие ускорения'; 
               end  
               app.ListBoxRegressionArg.Value = [app.ListBoxRegressionArg.Value, Res]; %Запись данных
            end         
        end      
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Выставление позиции
            ScreenSize = get(0, 'ScreenSize'); %Get screen size
            ScreenWidth = ScreenSize(3); ScreenHeight = ScreenSize(4); %Размеры экрана
            UiWidth = app.Settings_option.Position(3); UiHeight = app.Settings_option.Position(4); %Размеры приложения
            app.Settings_option.Position = [ScreenWidth/2 - UiWidth/2 ScreenHeight/2 - UiHeight/2 UiWidth UiHeight]; %Размещение по центру экрана            
            app.UITableCoefficients.RowName = {'Вдоль', 'Поперек'}; %Подпись строк
            %Выставление коэффициентов
            if isfile(app.FileNameAngle)
               app.Data = dlmread(app.FileNameAngle); %Чтение исходных коэффициентов из файла
            end 
            for i = 1:size(app.Data,1)
               for j = 1:size(app.Data,2)
                  DataStr{i,j} = sprintf('%f',app.Data(i,j)); %Изменение формата записи
               end               
            end                               
            app.UITableCoefficients.Data = DataStr; %Заполнение полей таблицы
            app.UITableCoefficients.ColumnFormat = {'numeric','numeric','numeric'}; %Ввод в поля ячеек только числовых значений
            
            if ~isfile(app.FileNameAngle)
               SaveCoeff(app); %Сохранение коэффициентов по умолчанию в файл 
            end
            if ~isfile(app.FileNameSettings)
               SaveSettings(app); %Сохранение настроек по умолчанию в файл 
            else
               ReadSettings(app); %Чтение настроек из файла
            end
            
        end

        % Cell edit callback: UITableCoefficients
        function UITableCoefficientsCellEdit(app, event)
            SaveCoeff(app);
        end

        % Value changed function: ListBoxRegressionArg
        function ListBoxRegressionArgValueChanged(app, event)
            SaveSettings(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Settings_option = uifigure;
            app.Settings_option.Name = 'Настройки';
            app.TabGroup = uitabgroup(app.Settings_option);
            app.Save = uitab(app.TabGroup);
            app.Save.Title = 'Сохранение';
            app.Label = uilabel(app.Save);
            app.Label.Text = 'Аргументы совмещенной таблицы';
            app.ListBoxRegressionArg = uilistbox(app.Save);
            app.ListBoxRegressionArg.Items = {'Исходные ускорения', 'Ускорения', 'Возрастающие ускорения', 'Нейтральные ускорения', 'Убывающие ускорения', 'Затухающие ускорения'};
            app.ListBoxRegressionArg.Multiselect = 'on';
            app.ListBoxRegressionArg.ValueChangedFcn = createCallbackFcn(app, @ListBoxRegressionArgValueChanged, true);
            app.ListBoxRegressionArg.Value = {'Исходные ускорения'};
            app.AngleCoeff = uitab(app.TabGroup);
            app.AngleCoeff.Title = 'Расчетные коэффициенты инклинометра';
            app.UITableCoefficients = uitable(app.AngleCoeff);
            app.UITableCoefficients.ColumnName = {'Ноль (Н)'; 'Коэффициент (К )'; 'Баланс (ББ)'; 'Калибровка (Д)'};
            app.UITableCoefficients.RowName = {};
            app.UITableCoefficients.ColumnEditable = [true true false true];
            app.UITableCoefficients.CellEditCallback = createCallbackFcn(app, @UITableCoefficientsCellEdit, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SettingsOption

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Settings_option)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Settings_option)
        end
    end
end