classdef Technical_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Technical_Data  matlab.ui.Figure
        Label           matlab.ui.control.Label
        FileName        matlab.ui.control.TextArea
    end


    properties (Access = public)
        ScreenSize %Размер экрана
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScreenSize = get(0, 'ScreenSize'); %Get screen size
            ScreenWidth = app.ScreenSize(3); ScreenHeight = app.ScreenSize(4); %Размеры экрана
            UiWidth = app.Technical_Data.Position(3); UiHeight = app.Technical_Data.Position(4); %Размеры приложения
            app.Technical_Data.Position = [ScreenWidth/2 - UiWidth/2 ScreenHeight/2 - UiHeight/2 UiWidth UiHeight]; %Размещение по центру экрана
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Technical_Data = uifigure;
            app.Technical_Data.Name = 'Справка';
            app.Label = uilabel(app.Technical_Data);
            app.Label.Text = 'Файл';
            app.FileName = uitextarea(app.Technical_Data);
            app.FileName.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Technical_data

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Technical_Data)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Technical_Data)
        end
    end
end